classdef Motioncontrolv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MotionControlPanel            matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        SetIncrementEditField         matlab.ui.control.NumericEditField
        SetIncrementEditFieldLabel    matlab.ui.control.Label
        MoveForwardbyIncrementButton  matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        motion; % NRT100
        forceSensor; % Lock in Amplifier
    end
    
    methods (Access = private)
        
        function StageActivation(app)
            fpos    = get(0,'DefaultFigurePosition'); % figure default position
            fpos(3) = 650*2/3; % figure window size;Width
            fpos(4) = 450*2/3; % Height

            fig = figure('Position', fpos,...
               'Menu','None',...
               'Name','APT GUI');
            app.motion = actxcontrol('MGMOTOR.MGMotorCtrl.1',[20 20 400 400*2/3 ], fig);
            app.motion.StartCtrl;
            SN2 = 90914852;
            set(app.motion,'HWSerialNum', SN2);
            jFrame2 = get(fig,'JavaFrame');
            jFrame2.setMinimized(true);
        end
        
        function lockin = LockIn(app)
            % Use instrfind to find existing instrument objects
            lockin = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::8::INSTR', 'Tag', '');
            % If there are no existing objects, create a new one
            if isempty(lockin)
                lockin = visa('ni', 'GPIB0::8::INSTR');
            else
                fopen(lockin);
            end

            % Set the number of samples to collect
            numSamples = 100;

            % Initialize an array to store the amplitude readings
            amplitude = zeros(numSamples, 1);

            % Initialize a time array
            time = zeros(numSamples, 1);

            % Start collecting data
            for i = 1:numSamples
                % Send the command to read the amplitude
                fprintf(lockin, 'OUTP? 3');

                % Read the amplitude
                amplitude(i) = fscanf(lockin, '%f');

                % Update the time array
                time(i) = (i-1)*0.1; % Assuming a sample rate of 0.1 seconds

                % Pause for a short time to allow the SR-830 to update
                pause(0.1);
            end

            % Plot the amplitude over time
            plot(time, amplitude);
            xlabel('Time (seconds)');
            ylabel('Amplitude');

            % Close the connection to the SR-830
            fclose(lockin);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StageActivation();
            pause(10);
            app.motion.MoveHome(0,0);
            
        end

        % Value changed function: SetIncrementEditField
        function SetIncrementEditFieldValueChanged(app, event)
            inc = app.SetIncrementEditField.Value;
            app.motion.SetVelParams(0,0.3)
            app.motion.SetRelMoveDist(0, inc)
        end

        % Button pushed function: MoveForwardbyIncrementButton
        function MoveForwardbyIncrementButtonPushed(app, event)
            app.motion.MoveRelative(0, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotionControlPanel = uipanel(app.UIFigure);
            app.MotionControlPanel.Title = 'Motion Control';
            app.StartButton = uibutton(app.MotionControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MoveForwardbyIncrementButton = uibutton(app.MotionControlPanel, 'push');
            app.MoveForwardbyIncrementButton.ButtonPushedFcn = createCallbackFcn(app, @MoveForwardbyIncrementButtonPushed, true);
            app.MoveForwardbyIncrementButton.Text = 'Move Forward by Increment';
            app.SetIncrementEditFieldLabel = uilabel(app.MotionControlPanel);
            app.SetIncrementEditFieldLabel.Text = 'Set Increment';
            app.SetIncrementEditField = uieditfield(app.MotionControlPanel, 'numeric');
            app.SetIncrementEditField.ValueChangedFcn = createCallbackFcn(app, @SetIncrementEditFieldValueChanged, true);
            app.SetIncrementEditField.Value = 3;
            app.ResetButton = uibutton(app.MotionControlPanel, 'push');
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motioncontrolv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end